classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        DutyCycleEditField  matlab.ui.control.NumericEditField
        EditFieldLabel      matlab.ui.control.Label
        Button              matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
arduino = serialport("COM6", 9600, "Timeout", 15);
pause(1)
num_points = 255;
DC = zeros(1, num_points);
V_res = zeros(1, num_points);
V_pc = zeros(1, num_points);
I = zeros(1, num_points);
R = zeros(1, num_points);
num_samples = 5; % 5 times

for K = 0:(num_points - 1)
    DC(K + 1) = K / 255 * 100; % Duty Cycle
    V_res_accum = 0; % 

    for sample = 1:num_samples
        flush(arduino);
        write(arduino, 2, 'string');
        pause(0.5);
        a = read(arduino, 4, 'string');
        V_res_accum = V_res_accum + str2double(a) / 1023 * 5;
        flush(arduino);
    end

    V_res(K + 1) = V_res_accum / num_samples; % average
    V_pc(K + 1) = 5 - V_res(K + 1);
    I(K + 1) = V_res(K + 1) / 500 * 1000; % Current (mA)
    R(K + 1) = V_pc(K + 1) / I(K + 1); % Resistance (kOhms)
    app.DutyCycleEditField.Value = DC(K + 1);

    % Update plots
    plot(app.UIAxes, DC(1:(K + 1)), V_pc(1:(K + 1)), 'bo', 'MarkerFaceColor', 'b')
    plot(app.UIAxes2, DC(1:(K + 1)), I(1:(K + 1)), 'bo', 'MarkerFaceColor', 'b')
    plot(app.UIAxes3, DC(1:(K + 1)), R(1:(K + 1)), 'bo', 'MarkerFaceColor', 'b')
end

delete(arduino); % Clean up

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.DutyCycleEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end